classdef DVZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        RightPanel   matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Self-defined properties
    properties (Access = private)
        Robot = struct( ...
            'x', 0, ...
            'y', 0, ...
            'theta', 0, ...
            'lin_vel', 0, ...
            'ang_vel', 0 ...
            );
        deltaT = 0.01;
    end
    
    % Self-defined methods
    methods (Access = private)
        % Update robot position based on velocities
        function updateRobotPose(app)
            app.Robot.x = app.Robot.x + app.deltaT * app.Robot.lin_vel * cos(app.Robot.theta);
            app.Robot.y = app.Robot.y + app.deltaT * app.Robot.lin_vel * sin(app.Robot.theta);
            app.Robot.theta = app.Robot.theta + app.deltaT * app.Robot.ang_vel;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.StartButton.Text == "Start")
                app.StartButton.Text = "Stop";
                % plot(app.UIAxes,[1 2 3 4],'-r');
            else
                app.StartButton.Text = "Start";
                % plot(app.UIAxes,[10 9 4 7],'--b');
            end
            
            t = 0:0.01:pi;
            plot(app.UIAxes, t, sin(t));
            % hold(app.UIAxes);
            % plot(app.UIAxes,[10 9 4 7],'--b');
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Update velocities based on key press
            switch event.Key
                case 'w'
                    app.Robot.lin_vel = app.Robot.lin_vel + 0.05;
                case 's'
                    app.Robot.lin_vel = app.Robot.lin_vel - 0.05;
                case 'a'
                    app.Robot.ang_vel = app.Robot.ang_vel + 0.01;
                case 'd'
                    app.Robot.ang_vel = app.Robot.ang_vel - 0.01;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DVZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end